classdef DobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint1Value              matlab.ui.control.NumericEditField
        Joint2Value              matlab.ui.control.NumericEditField
        Joint3Value              matlab.ui.control.NumericEditField
        LAUNCHButton             matlab.ui.control.Button
        SimulationButton         matlab.ui.control.Button
        EMERGENCYSTOPButton      matlab.ui.control.StateButton
        XEditFieldLabel          matlab.ui.control.Label
        X                        matlab.ui.control.EditField
        YEditFieldLabel          matlab.ui.control.Label
        Y                        matlab.ui.control.EditField
        ZEditFieldLabel          matlab.ui.control.Label
        Z                        matlab.ui.control.EditField
        REditFieldLabel          matlab.ui.control.Label
        Roll                     matlab.ui.control.EditField
        PEditFieldLabel          matlab.ui.control.Label
        Pitch                    matlab.ui.control.EditField
        YEditField_2Label        matlab.ui.control.Label
        Yaw                      matlab.ui.control.EditField
        LightCurtainButton       matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        RealRobotButton          matlab.ui.control.Button
        RESUMEButton             matlab.ui.control.Button
        InverseKinematicButton   matlab.ui.control.Button
        ObstacleAvoidanceButton  matlab.ui.control.Button
    end

    properties
        A2; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.Joint1Value.Value = value;
            app.A2.qTeach(1) = deg2rad(value);
            app.A2.qTeach(4) = pi - app.A2.qTeach(2) - app.A2.qTeach(3);
            app.A2.robot.model.animate(app.A2.qTeach)
            
            T = app.A2.robot.model.fkine(app.A2.qTeach);
            app.X.Value = num2str(round(T(1,4),2));
            app.Y.Value = num2str(round(T(2,4),2));
            app.Z.Value = num2str(round(T(3,4),2));
            
            RPY = tr2rpy(T,'deg');
            app.Roll.Value  = num2str(round(RPY(1),2));
            app.Pitch.Value = num2str(round(RPY(2),2));
            app.Yaw.Value   = num2str(round(RPY(3),2));
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.Joint2Value.Value = value;
            app.A2.qTeach(2) = deg2rad(value);
            app.A2.qTeach(4) = pi - app.A2.qTeach(2) - app.A2.qTeach(3);
            app.A2.robot.model.animate(app.A2.qTeach)
            
            T = app.A2.robot.model.fkine(app.A2.qTeach);
            app.X.Value = num2str(round(T(1,4),2));
            app.Y.Value = num2str(round(T(2,4),2));
            app.Z.Value = num2str(round(T(3,4),2));
            
            RPY = tr2rpy(T,'deg');
            app.Roll.Value  = num2str(round(RPY(1),2));
            app.Pitch.Value = num2str(round(RPY(2),2));
            app.Yaw.Value   = num2str(round(RPY(3),2));
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.Joint3Value.Value = value;
            app.A2.qTeach(3) = deg2rad(value);
            app.A2.qTeach(4) = pi - app.A2.qTeach(2) - app.A2.qTeach(3);
            app.A2.robot.model.animate(app.A2.qTeach)
            
            T = app.A2.robot.model.fkine(app.A2.qTeach);
            app.X.Value = num2str(round(T(1,4),2));
            app.Y.Value = num2str(round(T(2,4),2));
            app.Z.Value = num2str(round(T(3,4),2));
            
            RPY = tr2rpy(T,'deg');
            app.Roll.Value  = num2str(round(RPY(1),2));
            app.Pitch.Value = num2str(round(RPY(2),2));
            app.Yaw.Value   = num2str(round(RPY(3),2));
        end

        % Button pushed function: LAUNCHButton
        function LAUNCHButtonPushed(app, event)
            app.A2 = TokenSorterDobot();   
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
                app.A2.PickAndPlace(false);
        end

        % Value changed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonValueChanged(app, event)
            value = app.EMERGENCYSTOPButton.Value;
            app.A2.eStopState = value;
            if app.A2.eStopState
                app.Lamp.Color = 'r';
            else
                app.Lamp.Color = 'g';
            end
            
%             while(app.A2.eStopState)
%                 if ~app.A2.eStopState
%                     break
%                 end
%             end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            app.A2.eStopState = false;
        end

        % Button pushed function: InverseKinematicButton
        function InverseKinematicButtonPushed(app, event)
            x = str2double(app.X.Value);
            y = str2double(app.Y.Value);
            z = str2double(app.Z.Value);
            
            t = [x; y; z];
            R = rpy2r(0, 0, 0);
            T = rt2tr(R, t);
            q = app.A2.robot.model.ikcon(T,app.A2.qHome);
            q(4) = pi - q(2) - q(3); 
            q(5) = 0;
%             app.A2.robot.model.animate(q);
            
            app.Joint1Value.Value = round(q(1),2);
            app.Joint2Value.Value = round(q(2),2);
            app.Joint3Value.Value = round(q(3),2);
            
            qNow = app.A2.robot.model.getpos();
            qMatrix = jtraj(qNow, q, 50);
            for i = 1:50
                qGo = qMatrix(i,:);
                app.A2.robot.model.animate(qGo);
                drawnow();
            end
        end

        % Button pushed function: ObstacleAvoidanceButton
        function ObstacleAvoidanceButtonPushed(app, event)
            CollisionAvoidance();
        end

        % Button pushed function: LightCurtainButton
        function LightCurtainButtonPushed(app, event)
            LightCurtain();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-135 135];
            app.Joint1Slider.MajorTicks = [-135 0 135];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [5 80];
            app.Joint2Slider.MajorTicks = [5 45 80];
            app.Joint2Slider.MajorTickLabels = {'5', '45', '80'};
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Value = 45;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2 ';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [15 170];
            app.Joint3Slider.MajorTicks = [15 90 170];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Value = 90;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint1Value = uieditfield(app.UIFigure, 'numeric');
            app.Joint1Value.Limits = [-135 135];
            app.Joint2Value = uieditfield(app.UIFigure, 'numeric');
            app.Joint3Value = uieditfield(app.UIFigure, 'numeric');
            app.LAUNCHButton = uibutton(app.UIFigure, 'push');
            app.LAUNCHButton.ButtonPushedFcn = createCallbackFcn(app, @LAUNCHButtonPushed, true);
            app.LAUNCHButton.Text = 'LAUNCH';
            app.SimulationButton = uibutton(app.UIFigure, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'state');
            app.EMERGENCYSTOPButton.ValueChangedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonValueChanged, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.UIFigure, 'text');
            app.X.Value = '0';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z = uieditfield(app.UIFigure, 'text');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.Roll = uieditfield(app.UIFigure, 'text');
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.Pitch = uieditfield(app.UIFigure, 'text');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.Yaw = uieditfield(app.UIFigure, 'text');
            app.LightCurtainButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainButtonPushed, true);
            app.LightCurtainButton.Text = 'Light Curtain';
            app.Lamp = uilamp(app.UIFigure);
            app.RealRobotButton = uibutton(app.UIFigure, 'push');
            app.RealRobotButton.Text = 'Real Robot';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.InverseKinematicButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicButtonPushed, true);
            app.InverseKinematicButton.Text = 'Inverse Kinematic';
            app.ObstacleAvoidanceButton = uibutton(app.UIFigure, 'push');
            app.ObstacleAvoidanceButton.ButtonPushedFcn = createCallbackFcn(app, @ObstacleAvoidanceButtonPushed, true);
            app.ObstacleAvoidanceButton.Text = 'Obstacle Avoidance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end